classdef ImageProcessingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadImagePanel                matlab.ui.container.Panel
        LoadPredefined                matlab.ui.control.Button
        LoadCustomImg                 matlab.ui.control.Button
        PredefinedImagesLabel         matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        ImagePanel1                   matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        gray_lamp                     matlab.ui.control.Lamp
        RGBLabel                      matlab.ui.control.Label
        GrayscaleLabel                matlab.ui.control.Label
        r_lamp                        matlab.ui.control.Lamp
        g_lamp                        matlab.ui.control.Lamp
        b_lamp                        matlab.ui.control.Lamp
        OriginalImageSwitchLabel      matlab.ui.control.Label
        OriginalImageSwitch           matlab.ui.control.RockerSwitch
        SaveImageButton               matlab.ui.control.Button
        ImageSavedLabel               matlab.ui.control.Label
        BlurPanel                     matlab.ui.container.Panel
        SigmaKnobLabel                matlab.ui.control.Label
        SigmaKnob                     matlab.ui.control.Knob
        FilterSizeKnobLabel           matlab.ui.control.Label
        FilterSizeKnob                matlab.ui.control.DiscreteKnob
        PosterizePanel                matlab.ui.container.Panel
        NumberofcoloursLabel          matlab.ui.control.Label
        divSlider                     matlab.ui.control.Slider
        CelShadeLabel                 matlab.ui.control.Label
        AvgFilterPanel                matlab.ui.container.Panel
        AveragingFilterLabel          matlab.ui.control.Label
        CircularAveragingFilterLabel  matlab.ui.control.Label
        RadiusSpinnerLabel            matlab.ui.control.Label
        RadiusSpinner                 matlab.ui.control.Spinner
        FilterSizeLabel               matlab.ui.control.Label
        FilterSizeSpinner             matlab.ui.control.Spinner
        ColorProcessingPanel          matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        RGBChannelsButton             matlab.ui.control.Button
        HSVChannelsButton             matlab.ui.control.Button
        ColorConversionPanel          matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Cvt2GrayButton                matlab.ui.control.Button
        InvertColorButton             matlab.ui.control.Button
        ContrastBrightnessPanel       matlab.ui.container.Panel
        ContrastSliderLabel           matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        ContrastLabel                 matlab.ui.control.Label
        BrightnessLabel               matlab.ui.control.Label
        SharpenLabel                  matlab.ui.control.Label
        SharpenSliderLabel            matlab.ui.control.Label
        SharpenSlider                 matlab.ui.control.Slider
        SmoothLabel                   matlab.ui.control.Label
        SmoothSliderLabel             matlab.ui.control.Label
        SmoothSlider                  matlab.ui.control.Slider
        EdgedetectionFiltersPanel     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        LaplacianButton               matlab.ui.control.Button
        LoGButton                     matlab.ui.control.Button
        PrewittButton                 matlab.ui.control.Button
        SobelButton                   matlab.ui.control.Button
        CannyPanel                    matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        CannyEdgeDetectionButton      matlab.ui.control.Button
        AdjustmentPanel               matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        AdjustmentsButton             matlab.ui.control.Button
        BinarizePanel                 matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        BinarizeButton                matlab.ui.control.Button
        BinThreshPanel                matlab.ui.container.Panel
        BinarizewithThresholdLabel    matlab.ui.control.Label
        ThresholdSliderLabel          matlab.ui.control.Label
        ThresholdSlider               matlab.ui.control.Slider
        OrderStatisticPanel           matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        MinimumFilterButton           matlab.ui.control.Button
        MaximumFilterButton           matlab.ui.control.Button
        NoiseAdditionPanel            matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        GaussianButton                matlab.ui.control.Button
        SaltPepperButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        I % Original Image
        currentI
        tempI
        isgray=0
       
        defaultSigma = 1
        defaultFilterSize = '3' % For Discrete Knob, marking should be char/string
        defaultDiv = 16
    end
    
    methods (Access = private)
        
        function detectChannel(app)
            
            if strcmp(app.OriginalImageSwitch.Value,'Off')
                ch = size(app.currentI,3);
                if(ch==3)
                    app.gray_lamp.Enable='off';
                    app.GrayscaleLabel.Enable='off';
                    
                    app.r_lamp.Enable='on';
                    app.g_lamp.Enable='on';
                    app.b_lamp.Enable='on';
                    app.RGBLabel.Enable='on';
                    
                    app.Cvt2GrayButton.Enable='on';
                    app.RGBChannelsButton.Enable = "on";
                    app.HSVChannelsButton.Enable = "on";
                    
                elseif(ch==1)
                    app.gray_lamp.Enable='on';
                    app.GrayscaleLabel.Enable='on';
                    
                    app.r_lamp.Enable='off';
                    app.g_lamp.Enable='off';
                    app.b_lamp.Enable='off';
                    app.RGBLabel.Enable='off';
                    
                    app.Cvt2GrayButton.Enable='off';
                    app.RGBChannelsButton.Enable = "off";
                    app.HSVChannelsButton.Enable = "off";
                    
                end
            else
                ch = size(app.I,3);
                if(ch==3)
                    app.gray_lamp.Enable='off';
                    app.GrayscaleLabel.Enable='off';
                    
                    app.r_lamp.Enable='on';
                    app.g_lamp.Enable='on';
                    app.b_lamp.Enable='on';
                    app.RGBLabel.Enable='on';
                    
                    app.Cvt2GrayButton.Enable='on';
                    app.RGBChannelsButton.Enable = "on";
                    app.HSVChannelsButton.Enable = "on";
                    
                elseif(ch==1)
                    app.gray_lamp.Enable='on';
                    app.GrayscaleLabel.Enable='on';
                    
                    app.r_lamp.Enable='off';
                    app.g_lamp.Enable='off';
                    app.b_lamp.Enable='off';
                    app.RGBLabel.Enable='off';
                    
                    app.Cvt2GrayButton.Enable='off';
                    app.RGBChannelsButton.Enable = "off";
                    app.HSVChannelsButton.Enable = "off";
                    
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OriginalImageSwitch.Value = 'Off';
            app.I = imread(app.DropDown.Value);
            app.currentI = app.I;
            app.tempI = app.I;
            detectChannel(app);
            imshow(app.I,'Parent',app.UIAxes);  
            if(size(app.I,3)==1)
                app.RGBChannelsButton.Enable = "off";
                app.HSVChannelsButton.Enable = "off";
                app.isgray = 1;
            end
            app.ContrastSlider.Enable = "off";
            app.BrightnessSlider.Enable = "off";
            app.SmoothSlider.Enable = "off";
            app.SharpenSlider.Enable = "off";
            
            app.ImageSavedLabel.Enable = "off";
        end

        % Button pushed function: LoadPredefined
        function LoadPredefinedButtonPushed(app, event)
            app.I = imread(app.DropDown.Value);
            detectChannel(app);
            imshow(app.I,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            app.ContrastSlider.Value=0;
            app.BrightnessSlider.Value=0;
            app.SharpenSlider.Value=0;
            app.SmoothSlider.Value=0;
            app.ContrastLabel.Text="0";
            app.BrightnessLabel.Text="0";
            app.SharpenLabel.Text="0";
            app.SmoothLabel.Text="0";
            app.OriginalImageSwitch.Value="Off";
            
            app.currentI = app.I;
            app.tempI = app.I;
            detectChannel(app);
        end

        % Callback function
        function GaussianBlurButtonPushed(app, event)
            
        end

        % Value changing function: SigmaKnob
        function SigmaKnobValueChanging(app, event)
            changingValue = event.Value;
            
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            
            
            gaussianBlur_sigma = imgaussfilt(app.tempI,changingValue,'FilterSize',str2double(app.FilterSizeKnob.Value));
            imshow(gaussianBlur_sigma,'Parent',app.UIAxes);
            app.currentI = gaussianBlur_sigma;
            detectChannel(app);
            
            app.FilterSizeSpinner.Enable = "off";
            app.RadiusSpinner.Enable = "off";
        end

        % Value changed function: FilterSizeKnob
        function FilterSizeKnobValueChanged(app, event)
            value = app.FilterSizeKnob.Value;
            
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            
            gaussianBlur_fsize = imgaussfilt(app.tempI,app.SigmaKnob.Value,'FilterSize',str2double(value));
            imshow(gaussianBlur_fsize,'Parent',app.UIAxes);
            app.currentI = gaussianBlur_fsize;
            detectChannel(app);
            
            app.FilterSizeSpinner.Enable = "off";
            app.RadiusSpinner.Enable = "off";
        end

        % Value changing function: divSlider
        function divSliderValueChanging(app, event)
            div = round(event.Value);
            
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            
            numSteps = 256/div;
            stepdown = @(pixel) pixel-mod(pixel,numSteps);
            gaussBlurImg = imgaussfilt(app.I,app.SigmaKnob.Value,'FilterSize',str2double(app.FilterSizeKnob.Value));
            ShadedEffect=stepdown(gaussBlurImg) ;
            imshow(ShadedEffect,'Parent',app.UIAxes);
            app.currentI = ShadedEffect;
            detectChannel(app);
            
            app.CelShadeLabel.Text=string(div);
            app.Cvt2GrayButton.Enable = "off";
        end

        % Button pushed function: LoadCustomImg
        function LoadCustomPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            
            % p_name : Path Name, f_name : File Name
            [f_name, p_name] = uigetfile(filterspec);
            
            impath=strcat(p_name,f_name); %Complete image path
            app.I=imread(impath);
            imshow(app.I,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            app.ContrastSlider.Value=0;
            app.BrightnessSlider.Value=0;
            app.SharpenSlider.Value=0;
            app.SmoothSlider.Value=0;
            
            app.currentI = app.I;
            app.tempI = app.I;
            detectChannel(app);
        end

        % Button pushed function: Cvt2GrayButton
        function Cvt2GrayButtonPushed(app, event)
            %grayImg = rgb2gray(app.I); 
            grayImg = 0.299*app.I(:,:,1) + 0.587*app.I(:,:,2) + 0.114*app.I(:,:,3);
            imshow(grayImg,'Parent',app.UIAxes);
            
            app.SigmaKnob.Value = app.defaultSigma;
            app.FilterSizeKnob.Value = app.defaultFilterSize;
            app.divSlider.Value = app.defaultDiv;
            app.RadiusSpinner.Value = 1;
            app.FilterSizeSpinner.Value = 1;
            
            app.currentI = grayImg;
            app.tempI = grayImg;
            app.isgray=1;
            detectChannel(app);
        end

        % Value changed function: OriginalImageSwitch
        function OriginalImageSwitchValueChanged(app, event)
            value = app.OriginalImageSwitch.Value;
            if strcmp(value, 'On')
                imshow(app.I,'Parent',app.UIAxes);
                detectChannel(app);
            else
                imshow(app.currentI,'Parent',app.UIAxes);
                detectChannel(app);
            end
        end

        % Value changing function: FilterSizeSpinner
        function FilterSizeSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.RadiusSpinner.Value = 1;
            
            H = fspecial('average',changingValue);
            blurred = imfilter(app.currentI,H,'replicate'); 
            imshow(blurred,'Parent',app.UIAxes);
        end

        % Value changing function: RadiusSpinner
        function RadiusSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.FilterSizeSpinner.Value = 1;
            
            H = fspecial('disk',changingValue);
            blurred = imfilter(app.currentI,H,'replicate'); 
            imshow(blurred,'Parent',app.UIAxes);
        end

        % Button pushed function: InvertColorButton
        function InvertColorButtonPushed(app, event)
            invI = 255 - app.currentI;
            imshow(invI,'Parent',app.UIAxes);
            app.currentI = invI;
        end

        % Button pushed function: RGBChannelsButton
        function RGBChannelsButtonPushed(app, event)
            [r,g,b] = imsplit(app.I);
            montage({r,g,b},'Size',[1 3]);title("RGB Channels")
        end

        % Button pushed function: HSVChannelsButton
        function HSVChannelsButtonPushed(app, event)
            hsvImage = rgb2hsv(app.I);
            [h,s,v] = imsplit(hsvImage);
            montage({h,s,v},'Size',[1 3]);title("HSV Channels")
        end

        % Button pushed function: AdjustmentsButton
        function AdjustmentsButtonPushed(app, event)
            app.ContrastSlider.Enable = "on";
            app.BrightnessSlider.Enable = "on";
            app.SharpenSlider.Enable = "on";
            app.SmoothSlider.Enable = "on";

        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            val = round(event.Value);
            c = (val/100)*255;
            contrast = uint8(((259*(c+255))/(255*(259-c)))*(double(app.tempI)-128)+128);
            
            newI = contrast + app.BrightnessSlider.Value;
            imshow(newI,'Parent',app.UIAxes);
            app.currentI = newI;
            app.ContrastLabel.Text=string(val);
            app.SharpenSlider.Value=0;
            app.SmoothSlider.Value=0;
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            brightness = round(event.Value);            
            val = app.ContrastSlider.Value;
            c = (val/100)*255;
            contrast = uint8(((259*(c+255))/(255*(259-c)))*(double(app.tempI)-128)+128);
            
            newI = contrast + brightness;
            imshow(newI,'Parent',app.UIAxes);
            app.currentI = newI;
            app.BrightnessLabel.Text=string(brightness);
            app.SharpenSlider.Value=0;
            app.SmoothSlider.Value=0;
        end

        % Value changing function: SharpenSlider
        function SharpenSliderValueChanging(app, event)
            sharpen = round(event.Value);
            H = fspecial('laplacian');
            f=imfilter(app.tempI,H);
            newI = app.tempI+0.15*sharpen*f;
            
            imshow(newI,'Parent',app.UIAxes);
            app.currentI = newI;
            app.SharpenLabel.Text=string(sharpen);
            app.ContrastSlider.Value=0;
            app.BrightnessSlider.Value=0;
            app.SmoothSlider.Value=0;
            app.BrightnessLabel.Text="0";
            app.ContrastLabel.Text="0";
            app.SmoothLabel.Text="0";
        end

        % Value changing function: SmoothSlider
        function SmoothSliderValueChanging(app, event)
            medfilt = event.Value;
            y=round(12*(medfilt/100)+3);
            if(mod(y,2)==0)
                    y=y-1;
            end
            if(app.isgray)
                newI = medfilt2(app.tempI,[y y]);
            else
                newI = medfilt3(app.tempI,[y y 3]);
            end
            
            imshow(newI,'Parent',app.UIAxes);
            app.currentI = newI;
            app.SmoothLabel.Text=string(round(medfilt));
            app.ContrastSlider.Value=0;
            app.BrightnessSlider.Value=0;
            app.SharpenSlider.Value=0;
            app.SharpenLabel.Text="0";
            app.BrightnessLabel.Text="0";
            app.ContrastLabel.Text="0";
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            H = fspecial('laplacian',0);
            
            if(size(app.tempI,3)==3)
                filtImg = imfilter(rgb2gray(app.tempI),H);
            else
                filtImg = imfilter(app.tempI,H);
            end
            
            imshow(filtImg,'Parent',app.UIAxes);
            
            app.currentI = filtImg;
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Button pushed function: LoGButton
        function LoGButtonPushed(app, event)
            H = fspecial('log',[5 5],0.5);
            
            if(size(app.tempI,3)==3)
                filtImg = imfilter(rgb2gray(app.tempI),H);
            else
                filtImg = imfilter(app.tempI,H);
            end
            
            imshow(filtImg,'Parent',app.UIAxes);
            
            app.currentI = filtImg;
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            H = fspecial('prewitt');
            
            if(size(app.tempI,3)==3)
                filtImgH = imfilter(rgb2gray(app.tempI),H);
                filtImgV = imfilter(rgb2gray(app.tempI),H');
            else
                filtImgH = imfilter(app.tempI,H);
                filtImgV = imfilter(app.tempI,H');
            end
            
            figure;
            subplot(1,2,1)
            imshow(filtImgH);
            subplot(1,2,2)
            imshow(filtImgV)
            %montage({filtImgH,filtImgV},'Size',[1 2]);title("Prewitt Filter")  
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            H = fspecial('sobel');
            
            if(size(app.tempI,3)==3)
                filtImgH = imfilter(rgb2gray(app.tempI),H);
                filtImgV = imfilter(rgb2gray(app.tempI),H');
            else
                filtImgH = imfilter(app.tempI,H);
                filtImgV = imfilter(app.tempI,H');
            end
           
            figure;
            subplot(1,2,1)
            imshow(filtImgH);
            subplot(1,2,2)
            imshow(filtImgV)
            %montage({filtImgH,filtImgV},'Size',[1 2]);title("Sobel Filter")
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Button pushed function: CannyEdgeDetectionButton
        function CannyEdgeDetectionButtonPushed(app, event)
            if(size(app.tempI,3)==3)
                cannyE = edge(rgb2gray(app.tempI),'canny');
            else
                cannyE = edge(app.tempI,'canny');
            end
            
            imshow(cannyE,'Parent',app.UIAxes);
            app.currentI = cannyE;
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            if(size(app.tempI,3)==3)
                binImg = imbinarize(rgb2gray(app.tempI));
            else
                binImg = imbinarize(app.tempI);
            end
            
            imshow(binImg,'Parent',app.UIAxes);
            
            app.currentI = binImg;
            app.r_lamp.Enable='off';
            app.g_lamp.Enable='off';
            app.b_lamp.Enable='off';
            app.gray_lamp.Enable="off";
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            threshVal = event.Value;
            if(size(app.tempI,3)==3)
                binImg = imbinarize(rgb2gray(app.tempI),threshVal);
            else
                binImg = imbinarize(app.tempI,threshVal);
            end
            
            imshow(binImg,'Parent',app.UIAxes);
            
            app.currentI = binIng;
        end

        % Button pushed function: MinimumFilterButton
        function MinimumFilterButtonPushed(app, event)
            if(size(app.tempI,3)==3)
                minfilt = ordfilt2(rgb2gray(app.tempI),1,ones(3,3));
            else
                minfilt = ordfilt2(app.tempI,1,ones(3,3));
            end
            
            imshow(minfilt,'Parent',app.UIAxes);
            
            app.currentI = minfilt;
        end

        % Button pushed function: MaximumFilterButton
        function MaximumFilterButtonPushed(app, event)
            if(size(app.tempI,3)==3)
                maxfilt = ordfilt2(rgb2gray(app.tempI),9,ones(3,3));
            else
                maxfilt = ordfilt2(app.tempI,9,ones(3,3));
            end
            
            imshow(maxfilt,'Parent',app.UIAxes);
            
            app.currentI = maxfilt;
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            app.tempI = imnoise(app.tempI,'gaussian'); % 0 mean, 0.01 variance gaussian noise
            imshow(app.tempI,'Parent',app.UIAxes);
            
            app.currentI = app.tempI;
            
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            app.tempI = imnoise(app.tempI,'salt & pepper'); % affects approximately 5% of the pixel
            imshow(app.tempI,'Parent',app.UIAxes);
            
            app.currentI = app.tempI;
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            imwrite(app.currentI,'newImage.png');
            app.ImageSavedLabel.FontWeight="bold";
            app.ImageSavedLabel.FontSize = 12;
            app.ImageSavedLabel.Enable = "on";
            pause(1);
            app.ImageSavedLabel.FontSize=8;
            app.ImageSavedLabel.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadImagePanel = uipanel(app.UIFigure);
            app.LoadPredefined = uibutton(app.LoadImagePanel, 'push');
            app.LoadPredefined.ButtonPushedFcn = createCallbackFcn(app, @LoadPredefinedButtonPushed, true);
            app.LoadPredefined.Text = 'Load / Reset';
            app.LoadCustomImg = uibutton(app.LoadImagePanel, 'push');
            app.LoadCustomImg.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomPushed, true);
            app.LoadCustomImg.Text = 'Load Custom Image';
            app.PredefinedImagesLabel = uilabel(app.LoadImagePanel);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.LoadImagePanel);
            app.DropDown.Items = {'Peppers', 'Cameraman', 'Coins', 'Circuit', 'Rice Grains'};
            app.DropDown.ItemsData = {'peppers.png', 'cameraman.tif', 'coins.png', 'circuit.tif', 'rice.png'};
            app.DropDown.Value = 'peppers.png';
            app.ImagePanel1 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.ImagePanel1);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.gray_lamp = uilamp(app.ImagePanel1);
            app.RGBLabel = uilabel(app.ImagePanel1);
            app.RGBLabel.Text = 'RGB ';
            app.GrayscaleLabel = uilabel(app.ImagePanel1);
            app.GrayscaleLabel.Text = 'Grayscale';
            app.r_lamp = uilamp(app.ImagePanel1);
            app.g_lamp = uilamp(app.ImagePanel1);
            app.b_lamp = uilamp(app.ImagePanel1);
            app.OriginalImageSwitchLabel = uilabel(app.ImagePanel1);
            app.OriginalImageSwitchLabel.Text = 'Original Image';
            app.OriginalImageSwitch = uiswitch(app.ImagePanel1, 'rocker');
            app.OriginalImageSwitch.ValueChangedFcn = createCallbackFcn(app, @OriginalImageSwitchValueChanged, true);
            app.SaveImageButton = uibutton(app.ImagePanel1, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Tooltip = {'Save the image to current working directory'};
            app.SaveImageButton.Text = 'Save Image';
            app.ImageSavedLabel = uilabel(app.ImagePanel1);
            app.ImageSavedLabel.Text = 'Image Saved';
            app.BlurPanel = uipanel(app.UIFigure);
            app.BlurPanel.Title = 'Gaussian Blur';
            app.SigmaKnobLabel = uilabel(app.BlurPanel);
            app.SigmaKnobLabel.Text = 'Sigma';
            app.SigmaKnob = uiknob(app.BlurPanel, 'continuous');
            app.SigmaKnob.Limits = [0.1 10];
            app.SigmaKnob.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.SigmaKnob.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SigmaKnob.ValueChangingFcn = createCallbackFcn(app, @SigmaKnobValueChanging, true);
            app.SigmaKnob.MinorTicks = [];
            app.SigmaKnob.Value = 1;
            app.FilterSizeKnobLabel = uilabel(app.BlurPanel);
            app.FilterSizeKnobLabel.Text = 'FilterSize';
            app.FilterSizeKnob = uiknob(app.BlurPanel, 'discrete');
            app.FilterSizeKnob.Items = {'3', '5', '7', '9', '11', '13', '15'};
            app.FilterSizeKnob.ValueChangedFcn = createCallbackFcn(app, @FilterSizeKnobValueChanged, true);
            app.FilterSizeKnob.Value = '3';
            app.PosterizePanel = uipanel(app.UIFigure);
            app.PosterizePanel.Title = 'Posterize';
            app.NumberofcoloursLabel = uilabel(app.PosterizePanel);
            app.NumberofcoloursLabel.Text = 'Number of colours';
            app.divSlider = uislider(app.PosterizePanel);
            app.divSlider.Limits = [1 127];
            app.divSlider.MajorTicks = [];
            app.divSlider.MajorTickLabels = {''};
            app.divSlider.ValueChangingFcn = createCallbackFcn(app, @divSliderValueChanging, true);
            app.divSlider.MinorTicks = [];
            app.divSlider.Value = 32;
            app.CelShadeLabel = uilabel(app.PosterizePanel);
            app.CelShadeLabel.Text = '';
            app.AvgFilterPanel = uipanel(app.UIFigure);
            app.AveragingFilterLabel = uilabel(app.AvgFilterPanel);
            app.AveragingFilterLabel.Text = 'Averaging Filter';
            app.CircularAveragingFilterLabel = uilabel(app.AvgFilterPanel);
            app.CircularAveragingFilterLabel.Text = 'Circular Averaging Filter';
            app.RadiusSpinnerLabel = uilabel(app.AvgFilterPanel);
            app.RadiusSpinnerLabel.Text = 'Radius     :';
            app.RadiusSpinner = uispinner(app.AvgFilterPanel);
            app.RadiusSpinner.Step = 2;
            app.RadiusSpinner.ValueChangingFcn = createCallbackFcn(app, @RadiusSpinnerValueChanging, true);
            app.RadiusSpinner.Limits = [1 15];
            app.RadiusSpinner.RoundFractionalValues = 'on';
            app.RadiusSpinner.Editable = 'off';
            app.RadiusSpinner.Value = 1;
            app.FilterSizeLabel = uilabel(app.AvgFilterPanel);
            app.FilterSizeLabel.Text = 'Filter Size : ';
            app.FilterSizeSpinner = uispinner(app.AvgFilterPanel);
            app.FilterSizeSpinner.Step = 2;
            app.FilterSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @FilterSizeSpinnerValueChanging, true);
            app.FilterSizeSpinner.Limits = [1 15];
            app.FilterSizeSpinner.RoundFractionalValues = 'on';
            app.FilterSizeSpinner.Editable = 'off';
            app.FilterSizeSpinner.Value = 1;
            app.ColorProcessingPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.ColorProcessingPanel);
            app.RGBChannelsButton = uibutton(app.GridLayout3, 'push');
            app.RGBChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @RGBChannelsButtonPushed, true);
            app.RGBChannelsButton.Text = 'RGB  : Channels';
            app.HSVChannelsButton = uibutton(app.GridLayout3, 'push');
            app.HSVChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @HSVChannelsButtonPushed, true);
            app.HSVChannelsButton.Text = 'HSV : Channels';
            app.ColorConversionPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.ColorConversionPanel);
            app.Cvt2GrayButton = uibutton(app.GridLayout2, 'push');
            app.Cvt2GrayButton.ButtonPushedFcn = createCallbackFcn(app, @Cvt2GrayButtonPushed, true);
            app.Cvt2GrayButton.Text = {'Convert to Grayscale'; ''};
            app.InvertColorButton = uibutton(app.GridLayout2, 'push');
            app.InvertColorButton.ButtonPushedFcn = createCallbackFcn(app, @InvertColorButtonPushed, true);
            app.InvertColorButton.Text = 'Invert Color';
            app.ContrastBrightnessPanel = uipanel(app.UIFigure);
            app.ContrastSliderLabel = uilabel(app.ContrastBrightnessPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.ContrastBrightnessPanel);
            app.ContrastSlider.Limits = [-100 100];
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.MinorTicks = [];
            app.BrightnessSliderLabel = uilabel(app.ContrastBrightnessPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ContrastBrightnessPanel);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.MinorTicks = [];
            app.ContrastLabel = uilabel(app.ContrastBrightnessPanel);
            app.ContrastLabel.Text = '0';
            app.BrightnessLabel = uilabel(app.ContrastBrightnessPanel);
            app.BrightnessLabel.Text = '0';
            app.SharpenLabel = uilabel(app.ContrastBrightnessPanel);
            app.SharpenLabel.Text = '0';
            app.SharpenSliderLabel = uilabel(app.ContrastBrightnessPanel);
            app.SharpenSliderLabel.Text = 'Sharpen';
            app.SharpenSlider = uislider(app.ContrastBrightnessPanel);
            app.SharpenSlider.MajorTicks = [];
            app.SharpenSlider.ValueChangingFcn = createCallbackFcn(app, @SharpenSliderValueChanging, true);
            app.SharpenSlider.MinorTicks = [];
            app.SmoothLabel = uilabel(app.ContrastBrightnessPanel);
            app.SmoothLabel.Text = '0';
            app.SmoothSliderLabel = uilabel(app.ContrastBrightnessPanel);
            app.SmoothSliderLabel.Text = 'Smooth';
            app.SmoothSlider = uislider(app.ContrastBrightnessPanel);
            app.SmoothSlider.MajorTicks = [];
            app.SmoothSlider.MajorTickLabels = {''};
            app.SmoothSlider.ValueChangingFcn = createCallbackFcn(app, @SmoothSliderValueChanging, true);
            app.SmoothSlider.MinorTicks = [];
            app.EdgedetectionFiltersPanel = uipanel(app.UIFigure);
            app.EdgedetectionFiltersPanel.Title = 'Edge detection Filters';
            app.GridLayout4 = uigridlayout(app.EdgedetectionFiltersPanel);
            app.LaplacianButton = uibutton(app.GridLayout4, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.LoGButton = uibutton(app.GridLayout4, 'push');
            app.LoGButton.ButtonPushedFcn = createCallbackFcn(app, @LoGButtonPushed, true);
            app.LoGButton.Tooltip = {'Laplacian of Gaussian'};
            app.LoGButton.Text = 'LoG';
            app.PrewittButton = uibutton(app.GridLayout4, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.GridLayout4, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.CannyPanel = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.CannyPanel);
            app.CannyEdgeDetectionButton = uibutton(app.GridLayout5, 'push');
            app.CannyEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CannyEdgeDetectionButtonPushed, true);
            app.CannyEdgeDetectionButton.Text = 'Canny Edge Detection';
            app.AdjustmentPanel = uipanel(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.AdjustmentPanel);
            app.AdjustmentsButton = uibutton(app.GridLayout6, 'push');
            app.AdjustmentsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustmentsButtonPushed, true);
            app.AdjustmentsButton.Text = 'Adjustments';
            app.BinarizePanel = uipanel(app.UIFigure);
            app.GridLayout7 = uigridlayout(app.BinarizePanel);
            app.BinarizeButton = uibutton(app.GridLayout7, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Tooltip = {'Otsu''s method'};
            app.BinarizeButton.Text = 'Binarize';
            app.BinThreshPanel = uipanel(app.UIFigure);
            app.BinarizewithThresholdLabel = uilabel(app.BinThreshPanel);
            app.BinarizewithThresholdLabel.Text = 'Binarize : with Threshold';
            app.ThresholdSliderLabel = uilabel(app.BinThreshPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.BinThreshPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 0.5;
            app.OrderStatisticPanel = uipanel(app.UIFigure);
            app.GridLayout8 = uigridlayout(app.OrderStatisticPanel);
            app.MinimumFilterButton = uibutton(app.GridLayout8, 'push');
            app.MinimumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinimumFilterButtonPushed, true);
            app.MinimumFilterButton.Tooltip = {'Min value in the 3x3 window size'};
            app.MinimumFilterButton.Text = 'Minimum Filter';
            app.MaximumFilterButton = uibutton(app.GridLayout8, 'push');
            app.MaximumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaximumFilterButtonPushed, true);
            app.MaximumFilterButton.Tooltip = {'Max value in the 3x3 window'};
            app.MaximumFilterButton.Text = 'Maximum Filter';
            app.NoiseAdditionPanel = uipanel(app.UIFigure);
            app.NoiseAdditionPanel.Title = 'Noise Addition';
            app.GridLayout9 = uigridlayout(app.NoiseAdditionPanel);
            app.GaussianButton = uibutton(app.GridLayout9, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Tooltip = {'adds zero-mean Gaussian white noise with variance of 0.01 to grayscale image I'};
            app.GaussianButton.Text = 'Gaussian';
            app.SaltPepperButton = uibutton(app.GridLayout9, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Tooltip = {'adds salt and pepper noise with noise density 0.05. This affects approximately 5% of pixels.'};
            app.SaltPepperButton.Text = 'Salt & Pepper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end